classdef Cell_Count_Viewing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image                      matlab.ui.control.Image
        NormalizeCheckBox          matlab.ui.control.CheckBox
        SelectAnimalDropDown       matlab.ui.control.DropDown
        SelectAnimalDropDownLabel  matlab.ui.control.Label
        Graph                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cells = load('C:\Users\pk1020\Desktop\Cell_Count_Repo\celldata.mat');
        options;

        x = "Reference Slide Number";
        y = "Number of Cells";
        selected_animal;
        data
        xval;
        y_vmpfc
        y_ic
        y_t
        yval = 0;
        plotthis
        xax = 'Reference Slide Number';
        yax = 'Number of Cells';
        type = 0;
        typet = 'Raw';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.options = fieldnames(app.cells.cell_data.fixed);
            app.SelectAnimalDropDown.Items = app.options;
            app.SelectAnimalDropDown.Value = app.options(1);
            app.selected_animal = app.SelectAnimalDropDown.Value;
            app.Graph.XLabel.String = app.xax;
            app.Graph.YLabel.String = app.yax;
            %%app.Graph.Title.String = 
        end

        % Drop down opening function: SelectAnimalDropDown
        function SelectAnimalDropDownOpening(app, event)

        end

        % Value changed function: SelectAnimalDropDown
        function SelectAnimalDropDownValueChanged(app, event)
            app.selected_animal = app.SelectAnimalDropDown.Value;

            app.data = app.cells.cell_data.fixed.(app.selected_animal);


        switch app.type
            case 0  % raw count
                app.xval = app.data(1,:);
                app.yval = app.data(2:3,:);
          
                bar(app.Graph,app.xval,app.yval,.8,'stacked');
                title = sprintf('%s Cell Count Data for %s',app.typet, app.selected_animal);
            case 1  % normalized count
                app.xval = app.data(1,:);
                yvaltemp = app.data(2:3,:);
                app.yval = yvaltemp./app.cells.cell_data.spinal.(app.selected_animal);
          
                bar(app.Graph,app.xval,app.yval,.8,'stacked');
                title = sprintf('%s Cell Count Data for %s',app.typet, app.selected_animal);
        end
        app.Graph.Title.String = title;

            
        end

        % Button down function: Graph
        function GraphButtonDown(app, event)
           
        end

        % Callback function
        function GraphButtonPushed(app, event)

        end

        % Value changed function: NormalizeCheckBox
        function NormalizeCheckBoxValueChanged(app, event)
            if (app.type == 0)
                app.type = 1;
                app.typet = 'Normalized';
            elseif(app.type == 1)
                app.type = 0;
                app.typet = 'Raw';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Graph = uiaxes(app.UIFigure);
            title(app.Graph, 'Cell Count Data')
            xlabel(app.Graph, 'X')
            ylabel(app.Graph, 'Y')
            zlabel(app.Graph, 'Z')
            app.Graph.ButtonDownFcn = createCallbackFcn(app, @GraphButtonDown, true);
            app.SelectAnimalDropDownLabel = uilabel(app.UIFigure);
            app.SelectAnimalDropDownLabel.Text = 'Select Animal';
            app.SelectAnimalDropDown = uidropdown(app.UIFigure);
            app.SelectAnimalDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectAnimalDropDownOpening, true);
            app.SelectAnimalDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectAnimalDropDownValueChanged, true);
            app.NormalizeCheckBox = uicheckbox(app.UIFigure);
            app.NormalizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizeCheckBoxValueChanged, true);
            app.NormalizeCheckBox.Text = 'Normalize';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Slides8to57.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cell_Count_Viewing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end